classdef lidar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        EditField_2  matlab.ui.control.EditField
        EditField    matlab.ui.control.EditField
        clearButton  matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        HTML         matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            filename = app.EditField.Value;
            load(filename);

            p = Position;
            lat = p.latitude;
            lon = p.longitude;
            spd = p.speed;

            nBins = 10;
            binSpacing = (max(spd) - min(spd))/nBins; 
            binRanges = min(spd):binSpacing:max(spd)-binSpacing; 
            
            % Add an inf to binRanges to enclose the values above the last bin.
            binRanges(end+1) = inf;
            
            % |histc| determines which bin each speed value falls into.
            [~, spdBins] = histc(spd, binRanges);
            
            lat = lat';
            lon = lon';
            spdBins = spdBins';
            
            % Create a geographical shape vector, which stores the line segments as
            % features.
            s = geoshape();
            
            for k = 1:nBins
                
                % Keep only the lat/lon values which match the current bin. Leave the 
                % rest as NaN, which are interpreted as breaks in the line segments.
                latValid = nan(1, length(lat));
                latValid(spdBins==k) = lat(spdBins==k);
                
                lonValid = nan(1, length(lon));
                lonValid(spdBins==k) = lon(spdBins==k);    
            
                % To make the path continuous despite being segmented into different
                % colors, the lat/lon values that occur after transitioning from the
                % current speed bin to another speed bin will need to be kept.
                transitions = [diff(spdBins) 0];
                insertionInd = find(spdBins==k & transitions~=0) + 1;
            
                % Preallocate space for and insert the extra lat/lon values.
                latSeg = zeros(1, length(latValid) + length(insertionInd));
                latSeg(insertionInd + (0:length(insertionInd)-1)) = lat(insertionInd);
                latSeg(~latSeg) = latValid;
                
                lonSeg = zeros(1, length(lonValid) + length(insertionInd));
                lonSeg(insertionInd + (0:length(insertionInd)-1)) = lon(insertionInd);
                lonSeg(~lonSeg) = lonValid;
            
                % Add the lat/lon segments to the geographic shape vector.
                s(k) = geoshape(latSeg, lonSeg);
                
            end
            
%             wm = webmap('Open Street Map');
%             
%             mwLat = mean(lat);
%             mwLon = mean(lon);
%             name = 'Here';
%             iconDir = fullfile(matlabroot,'toolbox','matlab','icons');
%             iconFilename = fullfile(iconDir, 'matlabicon.gif');
%             wmmarker(mwLat, mwLon, 'FeatureName', name, 'Icon', iconFilename);
%             colors = autumn(nBins);
%             wmline(s, 'Color', colors, 'Width', 5);
%             wmzoom(16);
            app.HTML.HTMLSource = fullfile("mp4.html");
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
                disp('User selected cancel')
            else
                disp(['User selected', fullfile(path, file)])
                app.EditField.Value = fullfile(path, file);
            end

        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '位置情報取得';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '表示';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.Text = 'clear';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = 'オブジェクト読込';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lidar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end